classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        MainTab                  matlab.ui.container.Tab
        Panel_2                  matlab.ui.container.Panel
        titulo                   matlab.ui.control.Label
        tiempo                   matlab.ui.control.UIAxes
        psd_welch                matlab.ui.control.UIAxes
        espectro_wavelet         matlab.ui.control.UIAxes
        espectro_fft             matlab.ui.control.UIAxes
        ExportPanel              matlab.ui.container.Panel
        SaveButton               matlab.ui.control.Button
        Label_2                  matlab.ui.control.Label
        savename                 matlab.ui.control.EditField
        TitleLabel               matlab.ui.control.Label
        dropEXT                  matlab.ui.control.DropDown
        SourcePanel              matlab.ui.container.Panel
        PlotSignalButton         matlab.ui.control.Button
        SignalIDSpinnerLabel     matlab.ui.control.Label
        SignalIDSpinner          matlab.ui.control.Spinner
        DimentionEditFieldLabel  matlab.ui.control.Label
        DimentionEditField       matlab.ui.control.EditField
        DataBaseDropDownLabel    matlab.ui.control.Label
        DataBaseDropDown         matlab.ui.control.DropDown
        TypeofSignalPanel        matlab.ui.container.Panel
        HBLampLabel              matlab.ui.control.Label
        HBLamp                   matlab.ui.control.Lamp
        RELampLabel              matlab.ui.control.Label
        RELamp                   matlab.ui.control.Lamp
        LPLampLabel              matlab.ui.control.Label
        LPLamp                   matlab.ui.control.Lamp
        VTLampLabel              matlab.ui.control.Label
        VTLamp                   matlab.ui.control.Lamp
        TRLampLabel              matlab.ui.control.Label
        TRLamp                   matlab.ui.control.Lamp
        TCLampLabel              matlab.ui.control.Label
        TCLamp                   matlab.ui.control.Lamp
        IQLampLabel              matlab.ui.control.Label
        IQLamp                   matlab.ui.control.Lamp
        Image2                   matlab.ui.control.Image
        Tab2                     matlab.ui.container.Tab
    end


    properties (Access = private)
        Data % for the graphs 
        id_signal % number of signal
        cotopaxi % variable for values of migsiv
        laima % variable for values of laimgasig
       
    end


    methods (Access = private)
    
        
        function [signal,fs,station,type, start_p,end_p,country] = extraer_parametros(app, database,idx)
            % this function extract the parameters of the vulcano signals
                  
                 fs = database.SampleRate(idx); 
                 station = database.Station{idx}; 
                 type = database.Type{idx};
                 signal = database.Data{idx};
                 start_p= database.StartPoint{idx};
                 end_p= database.EndPoint{idx};
                 country=database.Network{idx};
%     
        end
        
        function act_lamp(app, label)
            %this function set le lamp with the type of signal 
            label=char(label);
          
            app.IQLamp.Color='red';
            app.TCLamp.Color='red';
            app.TRLamp.Color='red';
            app.VTLamp.Color='red';
            app.LPLamp.Color='red';
            app.RELamp.Color='red';
            app.HBLamp.Color='red';
            if length(label)>2
                if label=='REGIONAL'
                    app.RELamp.Color='green';
                elseif label=='ICEQUAKE'
                    app.IQLamp.Color='green';
                end
            else  
                 if label=='VT'
                    app.VTLamp.Color='green';
                elseif label=='LP'
                    app.LPLamp.Color='green';
                elseif label=='HB'
                    app.HBLamp.Color='green';
               
                elseif label=='TC'
                    app.TCLamp.Color='green';
                elseif-label=='TR'
                    app.TRLamp.Color='green';
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('MicSigV1.mat', 'MicSigV1');  %load the data of MicSigV1
            app.cotopaxi=MicSigV1;    %save the data in cotopaxi global variable
            load('laimaSigV1.mat', 'laimaSig'); %load the data if laimgaSigV1
            app.laima=laimaSig;   %save the data in cotopaxi global variable
            app.PlotSignalButton.Enable='off';
        end

        % Value changed function: DataBaseDropDown
        function DataBaseDropDownValueChanged(app, event)
            value = app.DataBaseDropDown.Value;
            switch value 

                case "DB-Laima"
                    app.PlotSignalButton.Enable='on';
                    dim=size(app.laima);
                    a=num2str(dim(1));
                    app.DimentionEditField.Value=a;
                    app.SignalIDSpinner.Value=1; 
                    app.id_signal=1;
                    app.Data=app.laima;
                    app.SignalIDSpinner.Limits=[1 dim(1)];
                case "DB-Cotopaxi"
                    app.PlotSignalButton.Enable='on';
                    dim=size(app.cotopaxi);
                    a=num2str(dim(1));
                    app.DimentionEditField.Value=a;
                 
                    app.SignalIDSpinner.Value=1;    
                    app.id_signal=1;
                    app.Data=app.cotopaxi;
                    app.SignalIDSpinner.Limits=[1 dim(1)];
                case "NONE"
                    
                    a='--';
                    app.DimentionEditField.Value=a;
                    val=1;
                    app.SignalIDSpinner.Value=val;
                    app.PlotSignalButton.Enable='off';
            end
        end

        % Button pushed function: PlotSignalButton
        function PlotSignalButtonPushed(app, event)
            app.id_signal = app.SignalIDSpinner.Value;
            
            [signal,Fs,station,type, start_p,end_p,country]=extraer_parametros(app, app.Data,app.id_signal);
            signal=signal(1,start_p:end_p); %
            signal=signal-mean(signal);
            time = (0:numel(signal)-1)/Fs;
            %time plot
            titlefig = ['Country: ',country,'               Station: ' station '             Type: ' type];
            app.titulo.Text=titlefig;
            
            act_lamp(app,type)
           
            plot(app.tiempo,time,signal,'-r')

            %psd-welch plot
            
            NFFT=512;
            pwelch_senal = pwelch(signal,hamming(NFFT),floor(0.5*NFFT),NFFT,Fs);
            twelch=linspace(0,50,length(pwelch_senal));
            plot(app.psd_welch,twelch,pwelch_senal,'Color','blue')
            
            
            %fft plot
%             n = length(signal);  
%                   
%             NFFT = 512; 
%             X = fft(signal,NFFT)/n;
%             f = (Fs/2)*linspace(0,1,NFFT/2+1);
%             Iv = 1:length(f);      
%       %      plot(app.espectro_fft,f,abs(X(Iv)));
%             imagesc(app.espectro_fft, time, f, abs(X(Iv)) )
            
            myAxe = app.espectro_fft;
            [S,F,T] = spectrogram(signal,98,50,512,1e2);
            imagesc(myAxe, T, F, log(1+abs(S)) )
            %imagesc(myAxe, 10*T, F/10, log(1+abs(S)) )
            
             view(myAxe,[-90 90])
             set(myAxe,'YDir','normal')          
            view(app.espectro_fft,[-270 90])
            set(app.espectro_fft,'XDir','reverse')
            shading(app.espectro_fft ,'flat')
            axis(app.espectro_fft , 'tight')
            
            % wavelet plot
            [cfs,frq] = cwt(signal,'morse',Fs);
            surf(app.espectro_wavelet,time,frq,log(1+abs(cfs)));
            view(app.espectro_wavelet,[-270 90])
            set(app.espectro_wavelet,'XDir','reverse')
            shading(app.espectro_wavelet ,'flat')
            axis(app.espectro_wavelet , 'tight')
            
        end

        % Value changing function: SignalIDSpinner
        function SignalIDSpinnerValueChanging(app, event)
            app.id_signal = event.Value;
            
        end

        % Value changed function: SignalIDSpinner
        function SignalIDSpinnerValueChanged(app, event)
            app.id_signal = app.SignalIDSpinner.Value;
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            value = app.dropEXT.Value;
            if app.savename =="" 
                
            elseif value ~= "EXT"
             
                % Copy all UIAxes children, take over axes limits and aspect ratio.            
                name =  ["-time","-PSD","-FFT","-Wavelet"] ;
                
                uiAx=[app.tiempo,app.psd_welch,app.espectro_fft,app.espectro_wavelet];
                for i=1:length(uiAx)
                  
                    fig = figure;
                    fig.Visible = 'off';
                    
                    figAxes = axes(fig);
                    allChildren = uiAx(i).XAxis.Parent.Children;
%                     
                    copyobj(allChildren,figAxes,"legacy")
                    figAxes.CameraPosition=uiAx(i).CameraPosition;
                    figAxes.CameraPositionMode=uiAx(i).CameraPositionMode;
                    figAxes.CameraTarget=uiAx(i).CameraTarget;
                    figAxes.CameraTargetMode=uiAx(i).CameraTargetMode;
                    figAxes.CameraUpVector=uiAx(i).CameraUpVector;
                    figAxes.CameraUpVectorMode=uiAx(i).CameraUpVectorMode;
                    figAxes.CameraViewAngle=uiAx(i).CameraViewAngle;
                    figAxes.CameraViewAngleMode=uiAx(i).CameraViewAngleMode;
                    
                    figAxes.View = uiAx(i).View;
                    figAxes.Projection=uiAx(i).Projection;
                    figAxes.LabelFontSizeMultiplier=uiAx(i).LabelFontSizeMultiplier;
                    figAxes.AmbientLightColor=uiAx(i).AmbientLightColor;                     
                    figAxes.DataAspectRatio = uiAx(i).DataAspectRatio;
                    figAxes.DataAspectRatioMode=uiAx(i).DataAspectRatioMode;
                    figAxes.PlotBoxAspectRatio=uiAx(i).PlotBoxAspectRatio;
                    figAxes.PlotBoxAspectRatioMode=uiAx(i).PlotBoxAspectRatioMode;
                    
                    figAxes.TickLabelInterpreter=uiAx(i).TickLabelInterpreter;
                    figAxes.XLim = uiAx(i).XLim;
                    figAxes.YLim = uiAx(i).YLim;
                    figAxes.ZLim = uiAx(i).ZLim;
                    figAxes.XDir=uiAx(i).XDir;
                    figAxes.YDir=uiAx(i).YDir;
                    figAxes.ZDir=uiAx(i).ZDir;
                    
                    figAxes.YLabel.String = uiAx(i).YLabel.String;
                    figAxes.YLabel.FontSize = uiAx(i).YLabel.FontSize;
                    figAxes.XLabel.String = uiAx(i).XLabel.String;
                    figAxes.XLabel.FontSize = uiAx(i).XLabel.FontSize;
                    %uncoment this lines if you need the title 
                    
%                     figAxes.Title.String = uiAx(i).Title.String;        
%                     figAxes.Title.FontSize = uiAx(i).Title.FontSize;
                    
                    auxname=app.savename.Value+name(i);
                    saveas(fig,auxname,value)
                    delete(fig);
                    auxname="";
                 
                end
                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.Panel_2 = uipanel(app.MainTab);
            app.titulo = uilabel(app.Panel_2);
            app.titulo.Text = '';
            app.tiempo = uiaxes(app.Panel_2);
            title(app.tiempo, 'Seismic Signal')
            xlabel(app.tiempo, 'Time')
            ylabel(app.tiempo, 'Amplitude')
            app.psd_welch = uiaxes(app.Panel_2);
            title(app.psd_welch, 'PSD-Welch')
            xlabel(app.psd_welch, 'Frequency')
            ylabel(app.psd_welch, 'Amplitude')
            app.espectro_wavelet = uiaxes(app.Panel_2);
            title(app.espectro_wavelet, 'Specgram Wavelet')
            xlabel(app.espectro_wavelet, 'Time')
            ylabel(app.espectro_wavelet, 'Frequency')
            app.espectro_fft = uiaxes(app.Panel_2);
            title(app.espectro_fft, 'Specgram FFT')
            xlabel(app.espectro_fft, 'Time ')
            ylabel(app.espectro_fft, 'Frequency')
            app.ExportPanel = uipanel(app.MainTab);
            app.ExportPanel.Title = 'Export';
            app.SaveButton = uibutton(app.ExportPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Label_2 = uilabel(app.ExportPanel);
            app.Label_2.Text = '';
            app.savename = uieditfield(app.ExportPanel, 'text');
            app.TitleLabel = uilabel(app.ExportPanel);
            app.TitleLabel.Text = 'Title';
            app.dropEXT = uidropdown(app.ExportPanel);
            app.dropEXT.Items = {'EXT', 'jpg', 'png', 'eps'};
            app.dropEXT.Value = 'EXT';
            app.SourcePanel = uipanel(app.MainTab);
            app.SourcePanel.Title = 'Source';
            app.PlotSignalButton = uibutton(app.SourcePanel, 'push');
            app.PlotSignalButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSignalButtonPushed, true);
            app.PlotSignalButton.Text = 'Plot Signal';
            app.SignalIDSpinnerLabel = uilabel(app.SourcePanel);
            app.SignalIDSpinnerLabel.Text = 'Signal ID';
            app.SignalIDSpinner = uispinner(app.SourcePanel);
            app.SignalIDSpinner.ValueChangingFcn = createCallbackFcn(app, @SignalIDSpinnerValueChanging, true);
            app.SignalIDSpinner.Limits = [1 3592];
            app.SignalIDSpinner.ValueChangedFcn = createCallbackFcn(app, @SignalIDSpinnerValueChanged, true);
            app.SignalIDSpinner.Value = 1;
            app.DimentionEditFieldLabel = uilabel(app.SourcePanel);
            app.DimentionEditFieldLabel.Text = 'Dimention';
            app.DimentionEditField = uieditfield(app.SourcePanel, 'text');
            app.DimentionEditField.Editable = 'off';
            app.DataBaseDropDownLabel = uilabel(app.SourcePanel);
            app.DataBaseDropDownLabel.Text = 'Data Base';
            app.DataBaseDropDown = uidropdown(app.SourcePanel);
            app.DataBaseDropDown.Items = {'NONE', 'DB-Laima', 'DB-Cotopaxi'};
            app.DataBaseDropDown.ValueChangedFcn = createCallbackFcn(app, @DataBaseDropDownValueChanged, true);
            app.DataBaseDropDown.Value = 'NONE';
            app.TypeofSignalPanel = uipanel(app.MainTab);
            app.TypeofSignalPanel.Title = 'Type of Signal';
            app.HBLampLabel = uilabel(app.TypeofSignalPanel);
            app.HBLampLabel.Text = 'HB';
            app.HBLamp = uilamp(app.TypeofSignalPanel);
            app.RELampLabel = uilabel(app.TypeofSignalPanel);
            app.RELampLabel.Text = 'RE';
            app.RELamp = uilamp(app.TypeofSignalPanel);
            app.LPLampLabel = uilabel(app.TypeofSignalPanel);
            app.LPLampLabel.Text = 'LP';
            app.LPLamp = uilamp(app.TypeofSignalPanel);
            app.VTLampLabel = uilabel(app.TypeofSignalPanel);
            app.VTLampLabel.Text = 'VT';
            app.VTLamp = uilamp(app.TypeofSignalPanel);
            app.TRLampLabel = uilabel(app.TypeofSignalPanel);
            app.TRLampLabel.Text = 'TR';
            app.TRLamp = uilamp(app.TypeofSignalPanel);
            app.TCLampLabel = uilabel(app.TypeofSignalPanel);
            app.TCLampLabel.Text = 'TC';
            app.TCLamp = uilamp(app.TypeofSignalPanel);
            app.IQLampLabel = uilabel(app.TypeofSignalPanel);
            app.IQLampLabel.Text = 'IQ';
            app.IQLamp = uilamp(app.TypeofSignalPanel);
            app.Image2 = uiimage(app.MainTab);
            app.Image2.ImageSource = 'SRASI.png';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end